classdef EllipticalCFST_AxialCapacityPrediction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GUI                           matlab.ui.Figure
        Label                         matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        InputvariablesPanel           matlab.ui.container.Panel
        CompressivestrengthofconcretefcMPaEditField_2Label  matlab.ui.control.Label
        CompressivestrengthofconcretefcMPaEditField_2  matlab.ui.control.NumericEditField
        LengthofcolumnLmmEditFieldLabel  matlab.ui.control.Label
        LengthofcolumnLmmEditField    matlab.ui.control.NumericEditField
        MajoraxislengthDmmEditFieldLabel  matlab.ui.control.Label
        MajoraxislengthDmmEditField   matlab.ui.control.NumericEditField
        MinoraxisllengthdmmEditFieldLabel  matlab.ui.control.Label
        MinoraxisllengthdmmEditField  matlab.ui.control.NumericEditField
        ThicknessofsteeltubemmLabel   matlab.ui.control.Label
        ThicknessofsteeltubemmEditField  matlab.ui.control.NumericEditField
        YieldstrengthofsteeltubefyMPaEditField_2Label  matlab.ui.control.Label
        YieldstrengthofsteeltubefyMPaEditField_2  matlab.ui.control.NumericEditField
        OutputPanel                   matlab.ui.container.Panel
        UltimateloadofCFSTcolumnQnkNEditFieldLabel  matlab.ui.control.Label
        UltimateloadofCFSTcolumnQnkNEditField  matlab.ui.control.NumericEditField
        StartpredictButton            matlab.ui.control.Button
        RangeLabel                    matlab.ui.control.Label
        RangeDensityPolymerSlider     matlab.ui.control.Slider
        Label_4                       matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        RangeSlider_2Label            matlab.ui.control.Label
        RangeYoungPolymerSlider       matlab.ui.control.Slider
        RangeSlider_2Label_2          matlab.ui.control.Label
        RangeTensilePolymerSlider     matlab.ui.control.Slider
        RangeSlider_2Label_3          matlab.ui.control.Label
        RangeWeightFractionSlider     matlab.ui.control.Slider
        RangeSlider_2Label_4          matlab.ui.control.Label
        RangeCNTDensitySlider         matlab.ui.control.Slider
        Label_5                       matlab.ui.control.Label
        Label_7                       matlab.ui.control.Label
        Label_9                       matlab.ui.control.Label
        Label_11                      matlab.ui.control.Label
        Label_6                       matlab.ui.control.Label
        Label_8                       matlab.ui.control.Label
        Label_10                      matlab.ui.control.Label
        Label_12                      matlab.ui.control.Label
        MinLabel                      matlab.ui.control.Label
        MaxLabel                      matlab.ui.control.Label
        Button                        matlab.ui.control.Button
        RangeSlider_2Label_5          matlab.ui.control.Label
        RangeCNTDensitySlider_2       matlab.ui.control.Slider
        Label_13                      matlab.ui.control.Label
        Label_14                      matlab.ui.control.Label
    end

    methods (Access = private)

        % Value changed function: RangeDensityPolymerSlider
        function RangeDensityPolymerSliderValueChanging(app, event)
            latestvalue = event.Value; % Current value
            app.LengthofcolumnLmmEditField.Value = latestvalue;  % Update value
        end

        % Value changed function: LengthofcolumnLmmEditField
        function LengthofcolumnLmmEditFieldValueChanged(app, event)
            latestvalue = event.Value; % Current value
            app.RangeDensityPolymerSlider.Value = latestvalue;  % Update value           
        end

        % Value changed function: MajoraxislengthDmmEditField
        function MajoraxislengthDmmEditFieldValueChanged(app, event)
            latestvalue = event.Value; % Current value
            app.RangeYoungPolymerSlider.Value = latestvalue;  % Update value
        end

        % Value changed function: RangeYoungPolymerSlider
        function RangeYoungPolymerSliderValueChanged(app, event)
            latestvalue = event.Value; % Current value
            app.MajoraxislengthDmmEditField.Value = latestvalue;  % Update value
        end

        % Value changed function: MinoraxisllengthdmmEditField
        function MinoraxisllengthdmmEditFieldValueChanged(app, event)
            latestvalue = event.Value; % Current value
            app.RangeTensilePolymerSlider.Value = latestvalue;  % Update value
        end

        % Value changed function: RangeTensilePolymerSlider
        function RangeTensilePolymerSliderValueChanged(app, event)
            latestvalue = event.Value; % Current value
            app.MinoraxisllengthdmmEditField.Value = latestvalue;  % Update value
        end

        % Value changed function: ThicknessofsteeltubemmEditField
        function ThicknessofsteeltubemmEditFieldValueChanged(app, event)
            latestvalue = event.Value; % Current value
            app.RangeWeightFractionSlider.Value = latestvalue;  % Update value
        end

        % Value changed function: RangeWeightFractionSlider
        function RangeWeightFractionSliderValueChanged(app, event)
            latestvalue = event.Value; % Current value
            app.ThicknessofsteeltubemmEditField.Value = latestvalue;  % Update value
        end

        % Value changed function: 
        % YieldstrengthofsteeltubefyMPaEditField_2
        function YieldstrengthofsteeltubefyMPaEditField_2ValueChanged(app, event)
            latestvalue = event.Value; % Current value
            app.RangeCNTDensitySlider.Value = latestvalue;  % Update value
        end

        % Value changed function: RangeCNTDensitySlider
        function RangeCNTDensitySliderValueChanged(app, event)
            latestvalue = event.Value; % Current value
            app.YieldstrengthofsteeltubefyMPaEditField_2.Value = latestvalue;  % Update value
        end

        % Callback function
        function CNTaveragediameternmEditFieldValueChanged(app, event)
            latestvalue = event.Value; % Current value
            app.RangeCNTDiameterSlider.Value = latestvalue;  % Update value
        end

        % Callback function
        function RangeCNTDiameterSliderValueChanged(app, event)
            latestvalue = event.Value; % Current value
            app.CNTaveragediameternmEditField.Value = latestvalue;  % Update value
        end

        % Callback function
        function CNTaveragelengthnmEditFieldValueChanged(app, event)
            latestvalue = event.Value; % Current value
            app.RangeCNTLengthSlider.Value = latestvalue;  % Update value
        end

        % Callback function
        function RangeCNTLengthSliderValueChanged(app, event)
            latestvalue = event.Value; % Current value
            app.CNTaveragelengthnmEditField.Value = latestvalue;  % Update value
        end

        % Callback function
        function YoungsmodulusofCNTGPaEditFieldValueChanged(app, event)
            latestvalue = event.Value; % Current value
            app.RangeCNTYoungSlider.Value = latestvalue;  % Update value
        end

        % Callback function
        function RangeCNTYoungSliderValueChanged(app, event)
            latestvalue = event.Value; % Current value
            app.YoungsmodulusofCNTGPaEditField.Value = latestvalue;  % Update value
        end

        % Button pushed function: StartpredictButton
        function StartpredictButtonPushed(app, event)
            clc
            warning('off', 'all')
            
            PolymerDensity = app.LengthofcolumnLmmEditField.Value;
            PolymerYoung = app.MajoraxislengthDmmEditField.Value;
            PolymerTensileStrength = app.MinoraxisllengthdmmEditField.Value;
            weightFraction = app.ThicknessofsteeltubemmEditField.Value;
            CNTDensity = app.YieldstrengthofsteeltubefyMPaEditField_2.Value;
            CNTDensity2 = app.CompressivestrengthofconcretefcMPaEditField_2.Value;
        
            X = [PolymerDensity PolymerYoung PolymerTensileStrength ...
                weightFraction CNTDensity CNTDensity2];
            
            
            NormalizationParameters = [160.000	136.5	63.1	1	201	13.18	413.3
                3600 318.5 155 9.72 439.3 102.26 2607];
            % Input vector in the normalization space:
            X_normalized = 2*(X - NormalizationParameters(1, 1:6))./(NormalizationParameters(2, 1:6) - NormalizationParameters(1, 1:6)) - 1;
            
            
            pwd;
            currentFolder = pwd;
            %disp(currentFolder)
            cd(currentFolder)

            try
                load([currentFolder, '\trainedModel.mat'])
            catch exception
                fmsgbox = msgbox({'Please check the working directory!'; 'Operation stopped.'});
                return
            end
            try
                % Prediction of Nu:
                Nu_predicted_normalized = evalfis(X_normalized, fis);
                %% Reverse mode of normalization of output Nu_predicted_normalized
                latestvalue = (Nu_predicted_normalized + 1)/2.*(NormalizationParameters(2, 7) - NormalizationParameters(1, 7)) + NormalizationParameters(1, 7);
                if latestvalue <= 0
                    fmsgbox = msgbox({'Lack of data for this configuration!'; 'Operation stopped.'});
                else
                    app.UltimateloadofCFSTcolumnQnkNEditField.Value = latestvalue;  % Update value
                end
            catch exception
                fmsgbox = msgbox({'Lack of data for this configuration!'; 'Operation stopped.'});
                return
            end
            
            
            %if exist('GPR.mat') ~= 2; delete(fmsgbox); end
            
        end

        % Value changed function: 
        % CompressivestrengthofconcretefcMPaEditField_2
        function CompressivestrengthofconcretefcMPaEditField_2ValueChanged(app, event)
            latestvalue = event.Value; % Current value
            app.RangeCNTDensitySlider_2.Value = latestvalue;  % Update value
        end

        % Value changed function: RangeCNTDensitySlider_2
        function RangeCNTDensitySlider_2ValueChanged(app, event)
            latestvalue = event.Value; % Current value
            app.CompressivestrengthofconcretefcMPaEditField_2.Value = latestvalue;  % Update value
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GUI = uifigure;
            app.GUI.Name = 'UI Figure';
            app.Label = uilabel(app.GUI);
            app.Label.Text = {'Graphical User Interface (GUI) for predicting axial capacity of elliptical concrete-filled steel tubular '; '(CFST) columns based on machine-learning model'};
            app.Label_2 = uilabel(app.GUI);
            app.Label_2.Text = {'This GUI is implemented by Dr. Tien-Thinh Le, Duy Tan University, Da Nang, Vietnam'; 'Email: letienthinh@duytan.edu.vn; tienthinhle.vn@gmail.com; Created with Matlab 2018a'};
            app.InputvariablesPanel = uipanel(app.GUI);
            app.InputvariablesPanel.Title = 'Input variables';
            app.CompressivestrengthofconcretefcMPaEditField_2Label = uilabel(app.InputvariablesPanel);
            app.CompressivestrengthofconcretefcMPaEditField_2Label.Text = 'Compressive strength of concrete f''c (MPa)';
            app.CompressivestrengthofconcretefcMPaEditField_2 = uieditfield(app.InputvariablesPanel, 'numeric');
            app.CompressivestrengthofconcretefcMPaEditField_2.Limits = [13.18 102.26];
            app.CompressivestrengthofconcretefcMPaEditField_2.ValueChangedFcn = createCallbackFcn(app, @CompressivestrengthofconcretefcMPaEditField_2ValueChanged, true);
            app.CompressivestrengthofconcretefcMPaEditField_2.Value = 40;
            app.LengthofcolumnLmmEditFieldLabel = uilabel(app.GUI);
            app.LengthofcolumnLmmEditFieldLabel.Text = 'Length of column L (mm)';
            app.LengthofcolumnLmmEditField = uieditfield(app.GUI, 'numeric');
            app.LengthofcolumnLmmEditField.Limits = [160 3600];
            app.LengthofcolumnLmmEditField.ValueChangedFcn = createCallbackFcn(app, @LengthofcolumnLmmEditFieldValueChanged, true);
            app.LengthofcolumnLmmEditField.Value = 1000;
            app.MajoraxislengthDmmEditFieldLabel = uilabel(app.GUI);
            app.MajoraxislengthDmmEditFieldLabel.Text = 'Major axis length D (mm)';
            app.MajoraxislengthDmmEditField = uieditfield(app.GUI, 'numeric');
            app.MajoraxislengthDmmEditField.Limits = [136.5 318.5];
            app.MajoraxislengthDmmEditField.ValueChangedFcn = createCallbackFcn(app, @MajoraxislengthDmmEditFieldValueChanged, true);
            app.MajoraxislengthDmmEditField.Value = 180;
            app.MinoraxisllengthdmmEditFieldLabel = uilabel(app.GUI);
            app.MinoraxisllengthdmmEditFieldLabel.Text = 'Minor axisl length d (mm)';
            app.MinoraxisllengthdmmEditField = uieditfield(app.GUI, 'numeric');
            app.MinoraxisllengthdmmEditField.Limits = [63.1 155];
            app.MinoraxisllengthdmmEditField.ValueChangedFcn = createCallbackFcn(app, @MinoraxisllengthdmmEditFieldValueChanged, true);
            app.MinoraxisllengthdmmEditField.Value = 90;
            app.ThicknessofsteeltubemmLabel = uilabel(app.GUI);
            app.ThicknessofsteeltubemmLabel.Text = 'Thickness of steel tube δ (mm)';
            app.ThicknessofsteeltubemmEditField = uieditfield(app.GUI, 'numeric');
            app.ThicknessofsteeltubemmEditField.Limits = [1 9.72];
            app.ThicknessofsteeltubemmEditField.ValueChangedFcn = createCallbackFcn(app, @ThicknessofsteeltubemmEditFieldValueChanged, true);
            app.ThicknessofsteeltubemmEditField.Value = 3;
            app.YieldstrengthofsteeltubefyMPaEditField_2Label = uilabel(app.GUI);
            app.YieldstrengthofsteeltubefyMPaEditField_2Label.Text = 'Yield strength of steel tube fy (MPa)';
            app.YieldstrengthofsteeltubefyMPaEditField_2 = uieditfield(app.GUI, 'numeric');
            app.YieldstrengthofsteeltubefyMPaEditField_2.Limits = [201 439.3];
            app.YieldstrengthofsteeltubefyMPaEditField_2.ValueChangedFcn = createCallbackFcn(app, @YieldstrengthofsteeltubefyMPaEditField_2ValueChanged, true);
            app.YieldstrengthofsteeltubefyMPaEditField_2.Value = 360;
            app.OutputPanel = uipanel(app.GUI);
            app.OutputPanel.Title = 'Output';
            app.UltimateloadofCFSTcolumnQnkNEditFieldLabel = uilabel(app.OutputPanel);
            app.UltimateloadofCFSTcolumnQnkNEditFieldLabel.Text = 'Ultimate load of CFST column Qn (kN)';
            app.UltimateloadofCFSTcolumnQnkNEditField = uieditfield(app.OutputPanel, 'numeric');
            app.StartpredictButton = uibutton(app.GUI, 'push');
            app.StartpredictButton.ButtonPushedFcn = createCallbackFcn(app, @StartpredictButtonPushed, true);
            app.StartpredictButton.Text = 'Start predict';
            app.RangeLabel = uilabel(app.GUI);
            app.RangeLabel.Text = 'Range:';
            app.RangeDensityPolymerSlider = uislider(app.GUI);
            app.RangeDensityPolymerSlider.Limits = [160 3600];
            app.RangeDensityPolymerSlider.MajorTicks = [];
            app.RangeDensityPolymerSlider.MajorTickLabels = {};
            app.RangeDensityPolymerSlider.ValueChangedFcn = createCallbackFcn(app, @RangeDensityPolymerSliderValueChanging, true);
            app.RangeDensityPolymerSlider.MinorTicks = [];
            app.RangeDensityPolymerSlider.Value = 1000;
            app.Label_4 = uilabel(app.GUI);
            app.Label_4.Text = '3600';
            app.Label_3 = uilabel(app.GUI);
            app.Label_3.Text = '160';
            app.RangeSlider_2Label = uilabel(app.GUI);
            app.RangeSlider_2Label.Text = 'Range:';
            app.RangeYoungPolymerSlider = uislider(app.GUI);
            app.RangeYoungPolymerSlider.Limits = [136.5 318.5];
            app.RangeYoungPolymerSlider.MajorTicks = [];
            app.RangeYoungPolymerSlider.MajorTickLabels = {};
            app.RangeYoungPolymerSlider.ValueChangedFcn = createCallbackFcn(app, @RangeYoungPolymerSliderValueChanged, true);
            app.RangeYoungPolymerSlider.MinorTicks = [];
            app.RangeYoungPolymerSlider.Value = 180;
            app.RangeSlider_2Label_2 = uilabel(app.GUI);
            app.RangeSlider_2Label_2.Text = 'Range:';
            app.RangeTensilePolymerSlider = uislider(app.GUI);
            app.RangeTensilePolymerSlider.Limits = [63.1 155];
            app.RangeTensilePolymerSlider.MajorTicks = [];
            app.RangeTensilePolymerSlider.MajorTickLabels = {};
            app.RangeTensilePolymerSlider.ValueChangedFcn = createCallbackFcn(app, @RangeTensilePolymerSliderValueChanged, true);
            app.RangeTensilePolymerSlider.MinorTicks = [];
            app.RangeTensilePolymerSlider.Value = 90;
            app.RangeSlider_2Label_3 = uilabel(app.GUI);
            app.RangeSlider_2Label_3.Text = 'Range:';
            app.RangeWeightFractionSlider = uislider(app.GUI);
            app.RangeWeightFractionSlider.Limits = [1 9.72];
            app.RangeWeightFractionSlider.MajorTicks = [];
            app.RangeWeightFractionSlider.MajorTickLabels = {};
            app.RangeWeightFractionSlider.ValueChangedFcn = createCallbackFcn(app, @RangeWeightFractionSliderValueChanged, true);
            app.RangeWeightFractionSlider.MinorTicks = [];
            app.RangeWeightFractionSlider.Value = 3;
            app.RangeSlider_2Label_4 = uilabel(app.GUI);
            app.RangeSlider_2Label_4.Text = 'Range:';
            app.RangeCNTDensitySlider = uislider(app.GUI);
            app.RangeCNTDensitySlider.Limits = [201 439.3];
            app.RangeCNTDensitySlider.MajorTicks = [];
            app.RangeCNTDensitySlider.MajorTickLabels = {};
            app.RangeCNTDensitySlider.ValueChangedFcn = createCallbackFcn(app, @RangeCNTDensitySliderValueChanged, true);
            app.RangeCNTDensitySlider.MinorTicks = [];
            app.RangeCNTDensitySlider.Value = 360;
            app.Label_5 = uilabel(app.GUI);
            app.Label_5.Text = '136.5';
            app.Label_7 = uilabel(app.GUI);
            app.Label_7.Text = '63.1';
            app.Label_9 = uilabel(app.GUI);
            app.Label_9.Text = '1';
            app.Label_11 = uilabel(app.GUI);
            app.Label_11.Text = '201';
            app.Label_6 = uilabel(app.GUI);
            app.Label_6.Text = '318.5';
            app.Label_8 = uilabel(app.GUI);
            app.Label_8.Text = '155';
            app.Label_10 = uilabel(app.GUI);
            app.Label_10.Text = '9.72';
            app.Label_12 = uilabel(app.GUI);
            app.Label_12.Text = '439.3';
            app.MinLabel = uilabel(app.GUI);
            app.MinLabel.Text = 'Min';
            app.MaxLabel = uilabel(app.GUI);
            app.MaxLabel.Text = 'Max';
            app.Button = uibutton(app.GUI, 'push');
            app.Button.Icon = 'Schema.png';
            app.Button.Text = '';
            app.RangeSlider_2Label_5 = uilabel(app.GUI);
            app.RangeSlider_2Label_5.Text = 'Range:';
            app.RangeCNTDensitySlider_2 = uislider(app.GUI);
            app.RangeCNTDensitySlider_2.Limits = [13.18 102.26];
            app.RangeCNTDensitySlider_2.MajorTicks = [];
            app.RangeCNTDensitySlider_2.MajorTickLabels = {};
            app.RangeCNTDensitySlider_2.ValueChangedFcn = createCallbackFcn(app, @RangeCNTDensitySlider_2ValueChanged, true);
            app.RangeCNTDensitySlider_2.MinorTicks = [];
            app.RangeCNTDensitySlider_2.Value = 40;
            app.Label_13 = uilabel(app.GUI);
            app.Label_13.Text = '102.26';
            app.Label_14 = uilabel(app.GUI);
            app.Label_14.Text = '13.18';
        end
    end

    methods (Access = public)

        % Construct app
        function app = EllipticalCFST_AxialCapacityPrediction

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GUI)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GUI)
        end
    end
end